classdef expStart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SessionconfigurationUIFigure  matlab.ui.Figure
        SubjectsIDEditFieldLabel      matlab.ui.control.Label
        SubjectsIDEditField           matlab.ui.control.EditField
        HandednessButtonGroup         matlab.ui.container.ButtonGroup
        RighthandedButton             matlab.ui.control.RadioButton
        LefthandedButton              matlab.ui.control.RadioButton
        GenderButtonGroup             matlab.ui.container.ButtonGroup
        MaleButton                    matlab.ui.control.RadioButton
        FemaleButton                  matlab.ui.control.RadioButton
        SubjectsageEditFieldLabel     matlab.ui.control.Label
        SubjectsageEditField          matlab.ui.control.EditField
        VisionButtonGroup             matlab.ui.container.ButtonGroup
        NormalButton                  matlab.ui.control.RadioButton
        CorrectedButton               matlab.ui.control.RadioButton
        OtherButton                   matlab.ui.control.RadioButton
        CommentsLabel                 matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
        SessionconfigurationLabel     matlab.ui.control.Label
        SaveandstartButton            matlab.ui.control.Button
        ResearcherLabel               matlab.ui.control.Label
        ResearcherDropDown            matlab.ui.control.DropDown
        SoundtestButton               matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        ScreendistanciecmEditFieldLabel  matlab.ui.control.Label
        ScreendistanciecmEditField    matlab.ui.control.EditField
        CapSizeDropDownLabel          matlab.ui.control.Label
        CapSizeDropDown               matlab.ui.control.DropDown
        HeadsizecmEditFieldLabel      matlab.ui.control.Label
        HeadsizecmEditField           matlab.ui.control.EditField
        NetworkIDDropDownLabel        matlab.ui.control.Label
        NetworkIDDropDown             matlab.ui.control.DropDown
        DonotsavecomportamentaldataCheckBox  matlab.ui.control.CheckBox
        TriggertestButton             matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            SUBJECT_INFO.id = false;
            assignin('base', 'SUBJECT_INFO', SUBJECT_INFO);
            uiresume(app.SessionconfigurationUIFigure);
            delete(app);
        end

        % Button pushed function: SaveandstartButton
        function SaveandstartButtonPushed(app, event)
            
            [~,struc] = fileattrib;
            PathCurrent = struc.Name;
            
            path(path,[PathCurrent '/func']);
            path(path,[PathCurrent '/mexf/io64']);
            
            % Create a data struct:
            SUBJECT_INFO.id = app.SubjectsIDEditField.Value;
            SUBJECT_INFO.age = app.SubjectsageEditField.Value;
            SUBJECT_INFO.comments = app.CommentsTextArea.Value;
            SUBJECT_INFO.researcher = app.ResearcherDropDown.Value;
            SUBJECT_INFO.capsize = app.CapSizeDropDown.Value;
            SUBJECT_INFO.networkID = app.NetworkIDDropDown.Value;
            SUBJECT_INFO.headSize = app.HeadsizecmEditField.Value;
            SUBJECT_INFO.screenDistance = app.ScreendistanciecmEditField.Value;
            
            if app.DonotsavecomportamentaldataCheckBox.Value == true
                SUBJECT_INFO.id = '0';
            end
            
            if (app.RighthandedButton.Value == true)
                SUBJECT_INFO.handedness = 'Right-handed';
            else
                SUBJECT_INFO.handedness = 'Left-handed';
            end
            
            if (app.MaleButton.Value == true)
                SUBJECT_INFO.gender = 'Male';
            else
                SUBJECT_INFO.gender = 'Female';
            end
            
            if (app.NormalButton.Value == true)
                SUBJECT_INFO.vision = 'Normal';
            elseif (app.CorrectedButton.Value == true)
                SUBJECT_INFO.vision = 'Corrected';
            else
                SUBJECT_INFO.vision = 'Other';
            end
            
            date = clock;
            
            SUBJECT_INFO.timestamp = [int2str(date(3)) '/' int2str(date(2)) '/' int2str(date(1)) ' ' int2str(date(4)) ':' int2str(date(5))];
            
            % Check available
            if str2double(SUBJECT_INFO.id) ~= 0
                SUBJECT_INFO.dirName = (['../DATA/DATA_S' SUBJECT_INFO.id]);
                if exist(SUBJECT_INFO.dirName,'dir') == 0
                    mkdir (SUBJECT_INFO.dirName);
                    % Save to the workspace:
                    assignin('base', 'SUBJECT_INFO', SUBJECT_INFO);
                    uiresume(app.SessionconfigurationUIFigure);
                    delete(app);
                else
                    uiwait(errordlg('Upps! This directory already exist. Please delete it or change subject''s ID','Error: This directory already exist.','modal'));
                end
            else
                % Save to the workspace:
                assignin('base', 'SUBJECT_INFO', SUBJECT_INFO);
                uiresume(app.SessionconfigurationUIFigure);
                delete(app);
            end
        end

        % Button pushed function: SoundtestButton
        function SoundtestButtonPushed(app, event)
            app.SoundtestButton.Text = 'Playing audio...';
            run soundTest.m;
            app.SoundtestButton.Text = 'Sound test';
        end

        % Button pushed function: TriggertestButton
        function TriggertestButtonPushed(app, event)
            app.TriggertestButton.Text = 'Sending triggers...';
            run mexf/io64/test_pport.m;
            app.TriggertestButton.Text = 'Trigger test';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SessionconfigurationUIFigure = uifigure;
            app.SessionconfigurationUIFigure.Name = 'Session configuration';
            app.SubjectsIDEditFieldLabel = uilabel(app.SessionconfigurationUIFigure);
            app.SubjectsIDEditFieldLabel.Text = 'Subject''s ID:';
            app.SubjectsIDEditField = uieditfield(app.SessionconfigurationUIFigure, 'text');
            app.SubjectsIDEditField.Value = '001';
            app.HandednessButtonGroup = uibuttongroup(app.SessionconfigurationUIFigure);
            app.HandednessButtonGroup.Title = 'Handedness:';
            app.RighthandedButton = uiradiobutton(app.HandednessButtonGroup);
            app.RighthandedButton.Text = 'Right-handed';
            app.RighthandedButton.Value = true;
            app.LefthandedButton = uiradiobutton(app.HandednessButtonGroup);
            app.LefthandedButton.Text = 'Left-handed';
            app.GenderButtonGroup = uibuttongroup(app.SessionconfigurationUIFigure);
            app.GenderButtonGroup.Title = 'Gender:';
            app.MaleButton = uiradiobutton(app.GenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.GenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.SubjectsageEditFieldLabel = uilabel(app.SessionconfigurationUIFigure);
            app.SubjectsageEditFieldLabel.Text = 'Subject''s age:';
            app.SubjectsageEditField = uieditfield(app.SessionconfigurationUIFigure, 'text');
            app.SubjectsageEditField.Value = '18';
            app.VisionButtonGroup = uibuttongroup(app.SessionconfigurationUIFigure);
            app.VisionButtonGroup.Title = 'Vision:';
            app.NormalButton = uiradiobutton(app.VisionButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.CorrectedButton = uiradiobutton(app.VisionButtonGroup);
            app.CorrectedButton.Text = 'Corrected';
            app.OtherButton = uiradiobutton(app.VisionButtonGroup);
            app.OtherButton.Text = 'Other';
            app.CommentsLabel = uilabel(app.SessionconfigurationUIFigure);
            app.CommentsLabel.Text = 'Comments:';
            app.CommentsTextArea = uitextarea(app.SessionconfigurationUIFigure);
            app.CommentsTextArea.Value = {'No comments'};
            app.SessionconfigurationLabel = uilabel(app.SessionconfigurationUIFigure);
            app.SessionconfigurationLabel.Text = 'Session configuration:';
            app.SaveandstartButton = uibutton(app.SessionconfigurationUIFigure, 'push');
            app.SaveandstartButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandstartButtonPushed, true);
            app.SaveandstartButton.Text = 'Save and start';
            app.ResearcherLabel = uilabel(app.SessionconfigurationUIFigure);
            app.ResearcherLabel.Text = 'Researcher:';
            app.ResearcherDropDown = uidropdown(app.SessionconfigurationUIFigure);
            app.ResearcherDropDown.Items = {'David López', 'Alberto Sobrado', 'Chema'};
            app.ResearcherDropDown.Value = 'David López';
            app.SoundtestButton = uibutton(app.SessionconfigurationUIFigure, 'push');
            app.SoundtestButton.ButtonPushedFcn = createCallbackFcn(app, @SoundtestButtonPushed, true);
            app.SoundtestButton.Text = 'Sound test';
            app.CancelButton = uibutton(app.SessionconfigurationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ScreendistanciecmEditFieldLabel = uilabel(app.SessionconfigurationUIFigure);
            app.ScreendistanciecmEditFieldLabel.Text = 'Screen distancie (cm) :';
            app.ScreendistanciecmEditField = uieditfield(app.SessionconfigurationUIFigure, 'text');
            app.ScreendistanciecmEditField.Value = '80';
            app.CapSizeDropDownLabel = uilabel(app.SessionconfigurationUIFigure);
            app.CapSizeDropDownLabel.Text = 'Cap Size:';
            app.CapSizeDropDown = uidropdown(app.SessionconfigurationUIFigure);
            app.CapSizeDropDown.Items = {'54cm - Small', '56cm - Medium', '58cm - Large'};
            app.CapSizeDropDown.Value = '56cm - Medium';
            app.HeadsizecmEditFieldLabel = uilabel(app.SessionconfigurationUIFigure);
            app.HeadsizecmEditFieldLabel.Text = 'Head size (cm):';
            app.HeadsizecmEditField = uieditfield(app.SessionconfigurationUIFigure, 'text');
            app.HeadsizecmEditField.Value = ' 54';
            app.NetworkIDDropDownLabel = uilabel(app.SessionconfigurationUIFigure);
            app.NetworkIDDropDownLabel.Text = 'Network ID:';
            app.NetworkIDDropDown = uidropdown(app.SessionconfigurationUIFigure);
            app.NetworkIDDropDown.Items = {'MR1'};
            app.NetworkIDDropDown.Value = 'MR1';
            app.DonotsavecomportamentaldataCheckBox = uicheckbox(app.SessionconfigurationUIFigure);
            app.DonotsavecomportamentaldataCheckBox.Text = 'Do not save comportamental data';
            app.TriggertestButton = uibutton(app.SessionconfigurationUIFigure, 'push');
            app.TriggertestButton.ButtonPushedFcn = createCallbackFcn(app, @TriggertestButtonPushed, true);
            app.TriggertestButton.Text = 'Trigger test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = expStart

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SessionconfigurationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SessionconfigurationUIFigure)
        end
    end
end